classdef ESP32_TestController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label                       matlab.ui.control.Label
        SensorDistanciaSwitch       matlab.ui.control.Switch
        SensorDistanciaSwitchLabel  matlab.ui.control.Label
        MotoresSwitch               matlab.ui.control.Switch
        MotoresSwitchLabel          matlab.ui.control.Label
        ESP32Switch                 matlab.ui.control.ToggleSwitch
        ESP32SwitchLabel            matlab.ui.control.Label
        VelocidadSlider             matlab.ui.control.Slider
        VelocidadSliderLabel        matlab.ui.control.Label
        DistAxes                    matlab.ui.control.UIAxes
        VelAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %velocidad; % Description
        distancia;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global velocidad;
            global esp32;
            clear all;


        end

        % Value changed function: ESP32Switch
        function ESP32SwitchValueChanged(app, event)
            global esp32;
            global velocidad;
            value = app.ESP32Switch.Value;
           
            if strcmp(value, "On")
                esp32 = tcpclient('192.168.1.100', 1234);
            end

            if strcmp(value, "Off")
                clear esp32;
            end
        
        end

        % Value changed function: MotoresSwitch
        function MotoresSwitchValueChanged(app, event)
            global esp32;
            global velocidad;
            value = app.MotoresSwitch.Value;
            if strcmp(value, "On")
                 writeline(esp32,"codigo2");
                 
            end
            if strcmp(value, "Off")
                 writeline(esp32,'stop');
            end

        end

        % Callback function: not associated with a component
        function VelocidadSliderValueChanging(app, event)

        end

        % Value changed function: VelocidadSlider
        function VelocidadSliderValueChanged(app, event)
            global velocidad;
            global esp32;
            velocidad = app.VelocidadSlider.Value;
            writeline(esp32, int2str(velocidad));

        end

        % Value changing function: VelocidadSlider
        function VelocidadSliderValueChanging2(app, event)
            % global velocidad;
            % global esp32;
            % velocidad =  event.Value;
            % writeline(esp32, int2str(velocidad));
            % 
          
        end

        % Value changed function: SensorDistanciaSwitch
        function SensorDistanciaSwitchValueChanged(app, event)
            value = app.SensorDistanciaSwitch.Value;
            if strcmp(value, "On")
                writeline(esp32, "codigo1");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VelAxes = uiaxes(app.UIFigure);
            title(app.VelAxes, 'Velocidad')
            xlabel(app.VelAxes, 'X')
            ylabel(app.VelAxes, 'Y')
            zlabel(app.VelAxes, 'Z')
            app.DistAxes = uiaxes(app.UIFigure);
            title(app.DistAxes, 'Distancia')
            xlabel(app.DistAxes, 'X')
            ylabel(app.DistAxes, 'Y')
            zlabel(app.DistAxes, 'Z')
            app.VelocidadSliderLabel = uilabel(app.UIFigure);
            app.VelocidadSliderLabel.Text = 'Velocidad';
            app.VelocidadSlider = uislider(app.UIFigure);
            app.VelocidadSlider.ValueChangedFcn = createCallbackFcn(app, @VelocidadSliderValueChanged, true);
            app.VelocidadSlider.ValueChangingFcn = createCallbackFcn(app, @VelocidadSliderValueChanging2, true);
            app.ESP32SwitchLabel = uilabel(app.UIFigure);
            app.ESP32SwitchLabel.Text = 'ESP32';
            app.ESP32Switch = uiswitch(app.UIFigure, 'toggle');
            app.ESP32Switch.ValueChangedFcn = createCallbackFcn(app, @ESP32SwitchValueChanged, true);
            app.MotoresSwitchLabel = uilabel(app.UIFigure);
            app.MotoresSwitchLabel.Text = 'Motores';
            app.MotoresSwitch = uiswitch(app.UIFigure, 'slider');
            app.MotoresSwitch.ValueChangedFcn = createCallbackFcn(app, @MotoresSwitchValueChanged, true);
            app.SensorDistanciaSwitchLabel = uilabel(app.UIFigure);
            app.SensorDistanciaSwitchLabel.Text = 'Sensor Distancia';
            app.SensorDistanciaSwitch = uiswitch(app.UIFigure, 'slider');
            app.SensorDistanciaSwitch.ValueChangedFcn = createCallbackFcn(app, @SensorDistanciaSwitchValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESP32_TestController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end