classdef ESP32_TestController_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        IPEditField                 matlab.ui.control.EditField
        IPEditFieldLabel            matlab.ui.control.Label
        Label2                      matlab.ui.control.Label
        SensorDistanciaSwitch       matlab.ui.control.Switch
        SensorDistanciaSwitchLabel  matlab.ui.control.Label
        ESP32Switch                 matlab.ui.control.ToggleSwitch
        ESP32SwitchLabel            matlab.ui.control.Label
        VelocidadSlider             matlab.ui.control.Slider
        VelocidadSliderLabel        matlab.ui.control.Label
        DistAxes                    matlab.ui.control.UIAxes
        VelAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        velocidad; % Description
        distancia;
        esp32
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;

            
        end

        % Value changed function: ESP32Switch
        function ESP32SwitchValueChanged(app, event)

            value = app.ESP32Switch.Value;
           
            if strcmp(value, "On")
                %app.Label3.Text = string(app.IPEditField.Value);
                app.esp32 = tcpclient(app.IPEditField.Value, 1234);
                writeline(app.esp32,"On");
                
            end

            if strcmp(value, "Off")
                writeline(app.esp32,'stop');
                clear esp32;
            end
        
        end

        % Callback function
        function VelocidadSliderValueChanging(app, event)

        end

        % Value changed function: VelocidadSlider
        function VelocidadSliderValueChanged(app, event)
            app.velocidad = app.VelocidadSlider.Value;
            %app.Label.Text = string(velocidad);
            drawnow
            writeline(app.esp32, int2str(app.velocidad));

            x = 1:10;
            y = sin(x);
            plot(app.VelAxes,x,app.velocidad);

        end

        % Value changing function: VelocidadSlider
        function VelocidadSliderValueChanging2(app, event)
            % global velocidad;
            % global esp32;
            % velocidad =  event.Value;
            % writeline(esp32, int2str(velocidad));

          
        end

        % Value changed function: SensorDistanciaSwitch
        function SensorDistanciaSwitchValueChanged(app, event)
            value = app.SensorDistanciaSwitch.Value;
            x = 1:10;
            while strcmp(value, "On")
                app.distancia = readline(app.esp32);
                app.Label2.Text = string(app.distancia);
                drawnow
                if isnumeric(app.distancia)
                    plot(app.DistAxes,x,app.distancia);
                end                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VelAxes = uiaxes(app.UIFigure);
            title(app.VelAxes, 'Velocidad')
            xlabel(app.VelAxes, 'X')
            ylabel(app.VelAxes, 'Y')
            zlabel(app.VelAxes, 'Z')
            app.DistAxes = uiaxes(app.UIFigure);
            title(app.DistAxes, 'Distancia')
            xlabel(app.DistAxes, 'X')
            ylabel(app.DistAxes, 'Y')
            zlabel(app.DistAxes, 'Z')
            app.VelocidadSliderLabel = uilabel(app.UIFigure);
            app.VelocidadSliderLabel.Text = 'Velocidad';
            app.VelocidadSlider = uislider(app.UIFigure);
            app.VelocidadSlider.Limits = [0 255];
            app.VelocidadSlider.ValueChangedFcn = createCallbackFcn(app, @VelocidadSliderValueChanged, true);
            app.VelocidadSlider.ValueChangingFcn = createCallbackFcn(app, @VelocidadSliderValueChanging2, true);
            app.ESP32SwitchLabel = uilabel(app.UIFigure);
            app.ESP32SwitchLabel.Text = 'ESP32';
            app.ESP32Switch = uiswitch(app.UIFigure, 'toggle');
            app.ESP32Switch.ValueChangedFcn = createCallbackFcn(app, @ESP32SwitchValueChanged, true);
            app.SensorDistanciaSwitchLabel = uilabel(app.UIFigure);
            app.SensorDistanciaSwitchLabel.Text = 'Sensor Distancia';
            app.SensorDistanciaSwitch = uiswitch(app.UIFigure, 'slider');
            app.SensorDistanciaSwitch.ValueChangedFcn = createCallbackFcn(app, @SensorDistanciaSwitchValueChanged, true);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.IPEditFieldLabel = uilabel(app.UIFigure);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESP32_TestController_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end