classdef RaspIMAQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        IPLamp                matlab.ui.control.Lamp
        IPLampLabel           matlab.ui.control.Label
        Switch                matlab.ui.control.ToggleSwitch
        SwitchLabel           matlab.ui.control.Label
        IPEditField           matlab.ui.control.EditField
        IPEditFieldLabel      matlab.ui.control.Label
        VideoSwitch           matlab.ui.control.Switch
        VideoSwitchLabel      matlab.ui.control.Label
        LedStatusSwitch       matlab.ui.control.Switch
        LedStatusSwitchLabel  matlab.ui.control.Label
        PictureButton         matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           clear mypi
                 

        end

        % Value changed function: LedStatusSwitch
        function LedStatusSwitchValueChanged(app, event)
            value = app.LedStatusSwitch.Value;
            global mypi
            global campi

            if strcmp(value,"On")
                writeLED(mypi,'led0',1);
                for ii = 1:10
                    img = snapshot(campi);
                    %imagesc(img);
                    imshow(img, 'Parent', app.UIAxes); 
                end
            end
            if strcmp(value,"Off")
                writeLED(mypi,'led0',false);
            end
        end

        % Callback function: Image, PictureButton
        function PictureButtonPushed(app, event)
            global mypi
            global campi
            image = snapshot(campi);
            image = snapshot(campi);%Si no se hace 2 veces toma la anterior
            filelocation = "C:\Users\56979\OneDrive - Universidad Técnica Federico Santa María (1)\ELO308";
            filename = "AppDesignerPic.png";
            fullFilename = fullfile(filelocation, filename);
            imwrite(image, fullFilename, "png");
            overWrittenImage = imread('AppDesignerPic.png');
            app.Image.ImageSource = overWrittenImage;


        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: VideoSwitch
        function VideoSwitchValueChanged(app, event)
            value = app.VideoSwitch.Value;
            flag = 0;
            global mypi
            global campi

           if strcmp(value,"On")
                 for ii = 1:100
                    img = snapshot(campi);
                    %imagesc(img);
                    imshow(img, 'Parent', app.UIAxes); 
                    pause(0.01);
                end
           end
            if strcmp(value,"Off")
                imshow("AppDesignerPic.png", 'Parent', app.UIAxes); 
                
                 
            end
               
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,"On")
                if strcmp(app.IPEditField,"")
                    app.IPLamp = "Red";
                else 
                    global mypi
                    global campi
                    mypi = raspi(string(app.IPEditField.Value),'pi','raspberry');
                    campi = cameraboard(mypi,"Resolution","1280x720");
                    campi.HorizontalFlip = true;
                    writeLED(mypi,'led0',false);   
                end
            end
                    

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @PictureButtonPushed, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'AppDesignerPic.png');
            app.PictureButton = uibutton(app.UIFigure, 'push');
            app.PictureButton.ButtonPushedFcn = createCallbackFcn(app, @PictureButtonPushed, true);
            app.PictureButton.Text = 'Picture';
            app.LedStatusSwitchLabel = uilabel(app.UIFigure);
            app.LedStatusSwitchLabel.Text = 'LedStatus';
            app.LedStatusSwitch = uiswitch(app.UIFigure, 'slider');
            app.LedStatusSwitch.ValueChangedFcn = createCallbackFcn(app, @LedStatusSwitchValueChanged, true);
            app.VideoSwitchLabel = uilabel(app.UIFigure);
            app.VideoSwitchLabel.Text = 'Video';
            app.VideoSwitch = uiswitch(app.UIFigure, 'slider');
            app.VideoSwitch.ValueChangedFcn = createCallbackFcn(app, @VideoSwitchValueChanged, true);
            app.IPEditFieldLabel = uilabel(app.UIFigure);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.UIFigure, 'text');
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.IPLampLabel = uilabel(app.UIFigure);
            app.IPLampLabel.Text = 'IP';
            app.IPLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RaspIMAQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end