classdef TestOne < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Image          matlab.ui.control.Image
        PreviewButton  matlab.ui.control.Button
        CaptureButton  matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    vidobj % Objeto de videoinput
    end      
    
    methods (Access = private)
        
        function startPreview(app)
            % Configuración del objeto videoinput
            app.vidobj = imaqhwinfo('winvideo',1);
            app.vidobj.ReturnedColorspace = 'rgb';
        
        % Configura el objeto videoinput para capturar imágenes en RGB
        app.vidobj.ReturnedColorspace = 'rgb';
        
        % Crea una función de devolución de llamada para mostrar la imagen en el objeto axes
        hImage = imshow(zeros(480, 640, 3), 'Parent', app.UIAxes);
        timerObj = timer('TimerFcn', @(~,~) set(hImage, 'CData', getsnapshot(app.vidobj)), 'Period', 0.1, 'ExecutionMode', 'fixedRate');
        
        % Inicia la captura de imágenes utilizando el temporizador
        start(timerObj);
        end
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CaptureButton, UIAxes
        function CaptureButtonPushed2(app, event)
        s%toppreview(app.vidobj);
        
        % Captura la imagen actual
        img = getsnapshot(app.vidobj);
        
        % Muestra la imagen en el objeto axes
        imshow(img, 'Parent', app.UIAxes);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            startPreview(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @CaptureButtonPushed2, true);
            app.CaptureButton = uibutton(app.UIFigure, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed2, true);
            app.CaptureButton.Text = 'Capture';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Almuerzos.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestOne

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end