classdef TestTwo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        FotoButton     matlab.ui.control.Button
        Image          matlab.ui.control.Image
        IniciarButton  matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            % Image acquisition
            v = videoinput('winvideo',1);

            % Create blank image
            hImage = image(app.UIAxes,zeros(720,1280,3)); 
            pause(2)
            
            % Preview the image
            preview(v,hImage)
        end

        % Button pushed function: FotoButton
        function FotoButtonPushed(app, event)
            v = videoinput('winvideo',1);

            image1 = getsnapshot(v);

            % Set the desired file location and name.
            filelocation = "C:\Users\56979\OneDrive - Universidad Técnica Federico Santa María (1)\ELO308";
            filename = "snapshot1.png";
            fullFilename = fullfile(filelocation, filename);

            % Write image data to file.
            imwrite(image1, fullFilename, "png");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'snapshot1.png');
            app.FotoButton = uibutton(app.UIFigure, 'push');
            app.FotoButton.ButtonPushedFcn = createCallbackFcn(app, @FotoButtonPushed, true);
            app.FotoButton.Text = 'Foto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestTwo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end